classdef TGVascutrack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        LoadFile                 matlab.ui.container.Menu
        Parms                    matlab.ui.container.Menu
        LoadParms                matlab.ui.container.Menu
        CreateParms              matlab.ui.container.Menu
        MaskMenu                 matlab.ui.container.Menu
        CreateMaskMenu           matlab.ui.container.Menu
        ProcessAllFramesMenu     matlab.ui.container.Menu
        BatchProcessMenu         matlab.ui.container.Menu
        CloseMenu                matlab.ui.container.Menu
        DiameterDetectionMenu    matlab.ui.container.Menu
        VesselEdgeDetectionMenu  matlab.ui.container.Menu
        AboutMenu                matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        Image                    matlab.ui.control.Image
        PlayPauseButton          matlab.ui.control.Button
        AverageLabel             matlab.ui.control.Label
        AveragecmsLabel          matlab.ui.control.Label
        Time                     matlab.ui.control.Label
        TimeLabel                matlab.ui.control.Label
        FrameLabel               matlab.ui.control.Label
        FrameSpinner             matlab.ui.control.Spinner
        FrameSpinnerLabel        matlab.ui.control.Label
        SavecurrentframedataasanarrayLabel  matlab.ui.control.Label
        SelectMaskDropDown       matlab.ui.control.DropDown
        SelectMaskDropDownLabel  matlab.ui.control.Label
        GinputButton             matlab.ui.control.StateButton
        Frequency                matlab.ui.control.EditField
        FrequencyHzLabel         matlab.ui.control.Label
        SaveButton               matlab.ui.control.StateButton
        FileNameLabel_2          matlab.ui.control.Label
        FileNameLabel            matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UIAxes_Raw               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        Property % Description
        data = double.empty;
        files = double.empty;
        Parameters = [];
        video;
        play = -1; %var for play/pause, -1 = not playing
        %FMD = double.empty;
        Profile;
        Diameter = double.empty;
    end


    methods (Access = private)

        function [height, time] = FilteringFrame (app)

            %frame = app.FrameSpinner.Value;
            frame = app.FrameSpinner.Value;

            data = read(app.video,frame);
            data = imcrop(data,app.Parameters.Parameters.rect);

            [n,m,~] = size(data);

            time = linspace(0,round(m / app.Parameters.Parameters.scaleTime),m);
            time = flip(-time);

            %% filtering the image
            data = imsharpen(data,'Radius',5,'Threshold',0);
            %app.FrameImg = data;

            % use the filtered selected in the list

            if app.SelectMaskDropDown.Value == "Default"
                BW = imbinarize(rgb2gray(data),"global");
            else
                [BW,~] = feval(app.SelectMaskDropDown.Value, data);
            end

            %% Removing the yellow line
            %yellowLineLoc = positionYelloLine(data);
            yellowLineLoc = positionXaxis(BW);
            if yellowLineLoc==0
                return
            end
            BW(yellowLineLoc,:) = 0;

            %data(yellowLineLoc,:) = 0;

            %% identify the line and the value
            deep_obj = bwpropfilt(BW,'MinorAxisLength',30);

            height = zeros(m,1);
            for n_col = 1:m

                if ~isempty(find(deep_obj(:,n_col) == 1, 1))

                    pts = find(deep_obj(:,n_col) == 1); %find loc of 1/pixels
                    chk_up = pts < mean(yellowLineLoc); %< means above as 0 y is on top!
                    if all(chk_up)  %check whether all points are above horiz line take first
                        height(n_col) = pts(find(chk_up == 1, 1, 'first')) ;

                    elseif all(~chk_up) %if all points are below horiz line take last
                        height(n_col) = pts(find(chk_up == 0, 1, "last"));

                    else %some points above and some below

                        posPt(1) = find(deep_obj(:,n_col),1,'first');
                        posPt(2) = find(deep_obj(:,n_col),1,'last');
                        height(n_col) = (sum(posPt)/2);
                    end
                else %if you do not find any point put the value of the x axis = 0
                    height(n_col) = mean(yellowLineLoc);
                end
                %correct based on y axis
                height(n_col) = mean(yellowLineLoc) - height(n_col);

            end
            height = height / (app.Parameters.Parameters.scale) ;

        end

        function [s] = displayTime(app)


            s = (1/app.video.FrameRate) * double(app.FrameSpinner.Value);
            %correct as the beginning is 00:00.00
            s = s-(1/app.video.FrameRate);

            s = seconds(s);
            s.Format = 'mm:ss.SS';
        end

        function BatchProcess(app)

            beep on  %just make a beep when finish
            Wbar = waitbar(0,'Starting analyze frames...');
            win_step = round(max(abs(app.data.time)) * app.video.FrameRate);
            Velocity = double.empty; %initialize variable

            for num_frame = app.FrameSpinner.Value : win_step : app.video.NumFrames

                waitbar(num_frame/app.video.NumFrames,Wbar,'Analyzing frames...')

                data = read(app.video,num_frame);
                data = imcrop(data,app.Parameters.Parameters.rect);
                %data = imresize(data,3); %increase x 3 for better data

                [n,m,~] = size(data);

                %time = linspace(0,round(m / app.Parameters.Parameters.scaleTime),m);

                %% filtering the image
                data = imsharpen(data,'Radius',5,'Threshold',0);
                %app.FrameImg = data;

                % use the filter selected in the list

                if app.SelectMaskDropDown.Value == "Default"
                    BW = imbinarize(rgb2gray(data),"global");
                else
                    [BW,~] = feval(app.SelectMaskDropDown.Value, data);
                end

                %% Removing the yellow line
                %yellowLineLoc = positionYelloLine(data);
                yellowLineLoc = positionXaxis(BW);
                if yellowLineLoc==0
                    return
                end
                BW(yellowLineLoc,:) = 0;

                %% identify the line and the value
                deep_obj = bwpropfilt(BW,'MinorAxisLength',30);

                height = zeros(m,1);
                for n_col = 1:m

                    if ~isempty(find(deep_obj(:,n_col) == 1, 1))

                        pts = find(deep_obj(:,n_col) == 1); %find loc of 1/pixels
                        chk_up = pts < mean(yellowLineLoc); %< means above as 0 y is on top!
                        if all(chk_up)  %check whether all points are above horiz line take first
                            height(n_col) = pts(find(chk_up == 1, 1, 'first')) ;

                        elseif all(~chk_up) %if all points are below horiz line take last
                            height(n_col) = pts(find(chk_up == 0, 1, "last"));

                        else %some points above and some below

                            posPt(1) = find(deep_obj(:,n_col),1,'first');
                            posPt(2) = find(deep_obj(:,n_col),1,'last');
                            height(n_col) = (sum(posPt)/2);
                        end
                    else %if you do not find any point put the value of the x axis = 0
                        height(n_col) = mean(yellowLineLoc);
                    end
                    %correct based on y axis
                    height(n_col) = mean(yellowLineLoc) - height(n_col);

                end
                height = height / (app.Parameters.Parameters.scale);
                %concatenate data to have a single profile-time trace
                Velocity = [Velocity height'];
            end
            close(Wbar);
            %save on the entire time window step
            %steps = app.FrameSpinner.Value  : win_step : app.video.NumFrames;
            %Time = linspace(0,app.video.Duration,length(Velocity))';
            StartTime = (app.FrameSpinner.Value -1)/app.video.FrameRate ;

            % 100Hz interp for between US comparison
            Time_100 = linspace(StartTime,app.video.Duration,(app.video.Duration-StartTime)*100);
            Velocity_100 = interp1(linspace(0,1,length(Velocity)), Velocity, linspace(0,1,length(Time_100)) )';
            Time_100 = round(Time_100-Time_100(1),3)';
            
            %Time array based on framerate
            Time = linspace(StartTime, app.video.Duration,(app.video.Duration-StartTime)*app.video.FrameRate);
            Time = Time - Time(1);

            %Interpolation based on framerate and not pixel of velocity
            %profile for having the same time scale with the diameter data
            Velocity = interp1(linspace(0,1,length(Velocity)), Velocity, linspace(0,1,length(Time)) );

            %struct to save
            Profile.Velocity = round(Velocity,3)';
            %Profile.Mean = mean(Profile.Velocity,2);
            Profile.Time = round(Time,3)';
            
            %save it local 
            app.Profile = Profile;

           
            %% save
            [path, name] = fileparts(app.files);
            
            if ~exist([path '/Elaborated/'], 'dir')
                mkdir([path '/Elaborated/'])
            end

            T   = array2table([Time_100, Velocity_100], 'VariableNames', {'Time(s)', 'Mean Raw Data (cm/s)'});
            %save mat
            Path_saveFile= [path '/Elaborated/' name]; %change
            %save xlsx
            save([Path_saveFile '.mat'],"Profile","Time_100","Velocity_100",'-mat')
            writetable(T,[Path_saveFile '.xlsx'],'Sheet','VelocityProfile')

            %% Plotting the results
            f = figure(1); clf

            plot(Profile.Time,Profile.Velocity,'LineWidth',2.5,'Color',[0.790 0.932 0.234]);
            hold on

            %ylim([min(FitData(:,2:3),[],'all')-10 max(FitData(:,2:3),[],'all')+10 ] )

            %just colorful stuff
            set(f,"Color",[0.18,0.20,0.28]);
            set(gca,"Color",[0.18,0.20,0.28],"YColor",[0.790 0.932 0.234],"XColor",[0.790 0.932 0.234],...
                "FontSize",16);

            title('Velocity-time trace', "Color",[0.790 0.932 0.234],"FontSize",22)
            ylabel('Velocity (cm/s)',"Color",[0.790 0.932 0.234])
            xlabel('Time (s)',"Color",[0.790 0.932 0.234])
            box off
            %legend("\color[rgb]{0.790 0.932 0.234} Raw","\color[rgb]{0.00,0.50,0.97} Smooth",'FontSize',20);

            %beep just to alert everything is done
            beep
            beep off
        end

        function Average_Calc(app)

            raw = round(mean(app.data.height,"all"),2);
            app.AverageLabel.Text = string(raw);
            %app.AverageFiltLabel.Text = string(filt);
        end

        function Update_Plots(app)

            limits = sort([min(app.data.height) max(app.data.height)]);
            limitsX = sort([min(app.data.time) max(app.data.time)]);
            if isnan(limits)
                msgbox('Be sure to have selected the right parms!')
            else

                limits = round(limits);
                %flip xaxis because the right is zero
                %app.UIAxes_Raw.XTick = 0:(app.data.time(end)/10):(app.data.time(end));
                app.UIAxes_Raw.XTick = min(app.data.time):max(app.data.time);
                app.UIAxes_Raw.YLim = ([limits(1)*1.15 limits(2)*1.15]);% [ -50 300];
                %app.UIAxes_Raw.YLim = [-50 250];
                app.UIAxes_Raw.YTick = limits(1)*1.15 : 10 : limits(2)*1.15;
                plot(app.UIAxes_Raw, app.data.time, app.data.height(:),'LineWidth',3,'Color',[0.47,0.67,0.19] ) ;
                %set(app.UIAxes_Raw, 'XDir','reverse'); %because on the right is 0
                %draw horizontal line at 0
                hold(app.UIAxes_Raw, 'on' )
                plot(app.UIAxes_Raw, limitsX, [0 0], 'LineWidth',3,'Color','red','LineStyle','--');
                drawnow;
                hold(app.UIAxes_Raw, 'off' )

            end
        end


        function Edge_DetectionAnalysis(app,selOpt)

            %%retrieve meta video and perform calibration
            pixelTocm = spatial_calibration(VideoReader(app.files),app.FrameSpinner.Value);

            %% Define crop area based on the specific frame

            data = read(app.video,app.FrameSpinner.Value);
            data = rgb2gray(data);

            fh = figure(1); clf
            %imshow(data);
            [~,rect] = imcrop(data);
            %title('Select area of interest')
            data = imcrop(data,rect);
            [n,m] = size(data);
            close(fh)

            %% Define line between two borders
            [ref_line] = Cut_Between(data);

            %% Initialize vectors
            Orientation = zeros(app.video.NumFrames,1);
            %dist = zeros(app.video.NumFrames,1);
            distance = zeros(app.video.NumFrames,1);

            %% Check options
            fh = figure(1); clf
            axesHandle = axes('Parent', fh);
            
            if selOpt(3) == true
                [vname, vpath] = uiputfile("*.mp4", "Select where to save the tracked video");
                videoObj = VideoWriter([vpath '/' vname],'MPEG-4');
                videoObj.FrameRate = app.video.FrameRate;
                open(videoObj);
            end

            Wbar = waitbar(0,'Analyzing...');

            %% Analyze the video
            for num_frame = app.FrameSpinner.Value : app.video.NumFrames
                    
                waitbar(num_frame/app.video.NumFrames,Wbar)

                %cut Frame
                data = read(app.video,num_frame);
                data = rgb2gray(data);
                data = imcrop(data,rect);

                %% Method
                %last parameter for applying filter or not based on the
                %tick
                [bordo_inferiore, bordo_superiore] = Border_Detection(data, ref_line, selOpt(2));

                %dist(ii) = mean(bordo_inferiore,'omitnan') - mean(bordo_superiore,'omitnan');
                distance(num_frame) = mean(bordo_inferiore - bordo_superiore,"all","omitnan");

                %Fit upper border
                p = polyfit(1:m, bordo_superiore,1);

                Up_X = [1 m];
                Up_Y = polyval(p,Up_X);

                %Fit lower border
                p = polyfit(1:m, bordo_inferiore,1);

                Low_X = [1 m];
                Low_Y = polyval(p,Low_X);

                %% Calculate angle between the two fit lines

                v_1 = [Up_X(2),Up_Y(2),0] - [Up_X(1),Up_Y(1),0];
                v_2 = [Low_X(2),Low_Y(2),0] - [Low_X(1),Low_Y(1),0];

                Orientation(num_frame) = atan2d(norm(cross(v_1,v_2)),dot(v_1,v_2));

                %% Update fig
                if selOpt(1) || selOpt(3)
                    figure(fh);

                    set(fh,"Visible",selOpt(1),'Resize','off');
                    imshow(data,'Parent', axesHandle)
                    hold on
                    plot(axesHandle,bordo_inferiore,'-','linew',1.5,'color','red')
                    %hold on
                    plot(axesHandle,bordo_superiore,'-','linew',1.5,'color','red')
                    title(axesHandle, ['Frame num: ' num2str(num_frame)])
                    drawnow
                    if selOpt(3) == true
                        F = getframe(fh);
                        writeVideo(videoObj,F);
                    end
                    hold off
                end

            end
            %close the figure
            close(fh)
            close(Wbar)

            if selOpt(3) == true
                close(videoObj);
            end
            %% cut the data according to the first analyzed frame
            
            StartTime = (app.FrameSpinner.Value -1)/app.video.FrameRate ;

            %create time array and cut it
            Time = round(linspace(0,app.video.Duration,app.video.NumFrames),3)';
            Time = Time(app.FrameSpinner.Value:end);
            Time = Time - Time(1); %rest time to zero

            Orientation = Orientation(app.FrameSpinner.Value:end);
            distance = distance(app.FrameSpinner.Value:end);            

            %remove outliers from orientation angles as some pts may affect
            %the results when the probe was unstable
            [~, tmp] =  rmoutliers(Orientation);

            %put nan where the outliers are
            Orientation(tmp == 1) = nan;
            %remove nan with fillmissing with the closest point
            Orientation = fillmissing(Orientation,'nearest');
            %calculate the perdipendicular distance of the average
            %vertical distance between the two border detected
            diameter = distance .* cosd(Orientation);

            %revert pix to cm
            %dist = dist' / pixelTocm;
            diameter = round(diameter / pixelTocm,3);
            diameterFilt = round(sgolayfilt(diameter,3,11),4);

            % 100Hz interp for between US comparison
            Time_100 = linspace(StartTime,app.video.Duration,(app.video.Duration-StartTime)*100);
            Diameter_100= interp1(linspace(0,1,length(diameter)), diameter, linspace(0,1,length(Time_100)) )';
            Time_100 = round(Time_100-Time_100(1),3)';

            %% Sexy figure
            app.Diameter = diameter;
            
            %Fancy figure
            figure('WindowState','normal'); clf

            plot(Time,diameter,'LineWidth',2.5,'Color',[0.790 0.932 0.234]);
            hold on
            plot(Time,diameterFilt,'LineWidth',2.5,'Color',[0.00,0.50,0.97]);

            %just colorful stuff
            set(gcf,"Color",[0.18,0.20,0.28]);
            set(gca,"Color",[0.18,0.20,0.28],"YColor",[0.790 0.932 0.234],"XColor",[0.790 0.932 0.234],...
                "FontSize",16);

            title('Vessel Diameter framy by frame', "Color",[0.790 0.932 0.234],"FontSize",22)
            ylabel('Diameter [cm]',"Color",[0.790 0.932 0.234])
            xlabel('Time (s) ',"Color",[0.790 0.932 0.234])
            box off
            legend("\color[rgb]{0.790 0.932 0.234} Diameter detection","\color[rgb]{0.00,0.50,0.97} Diameter filtered",'FontSize',20);

            %% Save
            [path, name] = fileparts(app.files);

            if ~exist([path '/Elaborated/'], 'dir')
                    mkdir([path '/Elaborated/'])
            end

            Path_saveFile= [path '/Elaborated/' name]; %change

            % save time and diameter data
            save([Path_saveFile '_Border.mat'],'Time','diameter','diameterFilt','pixelTocm','-mat');

            %save xlsx
            T = array2table([Time, diameter, diameterFilt],"VariableNames",{'Time(s)', 'Diameter (cm)','Diameter filtered (cm)'});
            writetable(T,[Path_saveFile '.xlsx'],'Sheet','Diameter');


        end

        function BatchFilesProcess(app)


            %get video file formats
            reader = 'VideoReader';
            file_formats = eval([reader '.getFileFormats']);
            format_list{1,1} = [];
            format_list{1,2} = 'All Video Files (';
            for i = 1:length(file_formats)
                format_list{1,1} = [format_list{1,1} '*.' file_formats(i).get.Extension ';'];
                format_list{1,2} = [format_list{1,2} file_formats(i).get.Description ', '];
                format_list{i+1,1} = ['*.' file_formats(i).get.Extension];
                format_list{i+1,2} = ['*.' file_formats(i).get.Extension ' - ' file_formats(i).get.Description];
            end

            format_list{1,2} = [format_list{1,2}(1:end-2) ')'];

            %pick up movies
            [files, fpath] = uigetfile(format_list,"MultiSelect","on");

            if length(files)<1
                return;
            end

            if ischar(files)==1
                files = {files}; %little trick to always have cell class also with one single file
            end

            beep on  %just make a beep when finish
            Wbarfiles = waitbar(0,'Starting...');

            for n_file = 1:length(files)

                waitbar(n_file/length(files),Wbarfiles,['File: ' num2str(n_file) '/' num2str(length(files))]);

                %load video
                video = VideoReader([fpath files{n_file}]);  %read/load video
                %read first frame ONLY to create the time windon and time step
                data = read(video,1);
                data = imcrop(data,app.Parameters.Parameters.rect);
                m = size(data,2);
                %Time steps
                time = linspace(0,round(m / app.Parameters.Parameters.scaleTime),m);
                win_step = round(abs(max(time)) * video.FrameRate);
               
                
                Velocity = double.empty; %initialize variable

                Wbar = waitbar(0,'Starting analyze frames...');

                for num_frame = 1 : win_step : video.NumFrames

                    waitbar(num_frame/video.NumFrames,Wbar,'Analyzing frames...')

                    data = read(video,num_frame);
                    data = imcrop(data,app.Parameters.Parameters.rect);
                    [n,m,~] = size(data);

                    time = linspace(0,round(m / app.Parameters.Parameters.scaleTime),m);

                    %% filtering the image
                    data = imsharpen(data,'Radius',5,'Threshold',0);

                    % use the filter selected in the list

                    if any([app.SelectMaskDropDown.Value == "Default" isempty(app.SelectMaskDropDown.Value) ])
                        BW = imbinarize(rgb2gray(data),"global");
                    else
                        [BW,~] = feval(app.SelectMaskDropDown.Value, data);
                    end
                    %% Removing the yellow line
                    yellowLineLoc = positionXaxis(BW);
                    if yellowLineLoc == 0
                        return
                    end
                    BW(yellowLineLoc,:) = 0;

                    %% identify the line and the value
                    deep_obj = bwpropfilt(BW,'MinorAxisLength',30);

                    height = zeros(m,1);
                    for n_col = 1:m

                        if ~isempty(find(deep_obj(:,n_col) == 1, 1))

                            pts = find(deep_obj(:,n_col) == 1); %find loc of 1/pixels
                            chk_up = pts < mean(yellowLineLoc); %< means above as 0 y is on top!
                            if all(chk_up)  %check whether all points are above horiz line take first
                                height(n_col) = pts(find(chk_up == 1, 1, 'first')) ;

                            elseif all(~chk_up) %if all points are below horiz line take last
                                height(n_col) = pts(find(chk_up == 0, 1, "last"));

                            else %some points above and some below

                                posPt(1) = find(deep_obj(:,n_col),1,'first');
                                posPt(2) = find(deep_obj(:,n_col),1,'last');
                                height(n_col) = (sum(posPt)/2);
                            end
                        else %if you do not find any point put the value of the x axis = 0
                            height(n_col) = mean(yellowLineLoc);
                        end
                        %correct based on y axis
                        height(n_col) = mean(yellowLineLoc) - height(n_col);

                    end
                    height = height / (app.Parameters.Parameters.scale);
                    %concatenate data to have a single profile-time trace
                    Velocity = [Velocity height'];

                end

                % 100Hz interp for between US comparison
                Time_100 = linspace(0,video.Duration,video.Duration*100);
                Velocity_100 = interp1(linspace(0,1,length(Velocity)), Velocity, linspace(0,1,length(Time_100)) )';
                Time_100 = round(Time_100-Time_100(1),3)';
            
                %Time
                Time = linspace(0,video.Duration, video.NumFrames)';
                Time = round(Time,3);

                %Interpolation based on framerate and not pixel of velocity
                %profile for having the same time scale with the diameter data
                Velocity = interp1(linspace(0,1,length(Velocity)), Velocity, linspace(0,1,length(Time)) )';

                %struct to save
                Profile.Velocity = Velocity;
                Profile.Time = Time;

                %% save
                if ~exist([fpath '/Elaborated/'], 'dir')
                    mkdir([fpath '/Elaborated/'])
                end

                fname = erase(files{n_file},'.mp4');
                T   = array2table([Time_100, Velocity_100], 'VariableNames', {'Time(s)', 'Velocity (cm/s)'});

                Path_saveFile= [fpath '/Elaborated/' fname]; %change
                %save mat and xlsx
                save([Path_saveFile '.mat'],"Profile","Velocity_100","Time_100",'-mat')
                writetable(T,[Path_saveFile '.xlsx'],'Sheet','VelocityProfile')

                close(Wbar);
            end
            close(Wbarfiles);
        end

        function selectedOptions = dlg_plotting_vessel(app)
            % Create the main figure
            fig = uifigure('Name', 'Options Vessel Detection', 'Position', [100, 100, 300, 150]);
            
            % Create checkboxes
            checkbox1 = uicheckbox(fig, 'Text', 'Real-Time Plotting', 'Position', [50, 100, 200, 22]);
            checkbox2 = uicheckbox(fig, 'Text', 'Apply vesselfilter2D', 'Position', [50, 70, 200, 22]);
            checkbox3 = uicheckbox(fig, 'Text', 'Save the tracked video', 'Position', [50, 40, 200, 22]);
            
            % Create "OK" button
            okButton = uibutton(fig, 'Text', 'OK', 'Position', [120, 10, 60, 30], 'ButtonPushedFcn', @(btn,event) okButtonPressed(fig));
            
            % Wait for the figure to close
            waitfor(fig);

            function okButtonPressed(fig)
                selectedOptions = [checkbox1.Value, checkbox2.Value, checkbox3.Value];
                delete(fig); % Close the figure
            end
        end


        function showHyperlinkDialog(app)

            % Create a uifigure
            fig = uifigure('Name', 'About', 'Position', [200, 200, 250, 150]);

            % Create a uilabel for the first line
            textLabel1 = uilabel(fig);
            textLabel1.Text = 'TGVascutrack©';
            textLabel1.FontSize = 16;
            textLabel1.Position = [20, 110, 260, 20];

            % Create a uilabel for the second line
            textLabel2 = uilabel(fig);
            textLabel2.Text = 'For more information:';
            textLabel2.FontSize = 16;
            textLabel2.Position = [20, 80, 180, 20];

            % Create a uihyperlink for displaying the hyperlink
            hlink = uihyperlink(fig);
            hlink.Text = 'GitHub';
            hlink.FontSize = 16;
            hlink.Position = [180, 80, 260, 20];
            hlink.URL = 'https://github.com/PaulT95/TG_VascuTrack';


            % Add a "Close" button
            closeButton = uibutton(fig, 'Text', 'Close', 'Position', [100, 10, 50, 30], 'ButtonPushedFcn', @(btn,event) closeDialog(fig));

            % Function to close the dialog
            function closeDialog(fig)
                delete(fig);
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {676, 676};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {303, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            if(isempty(app.video) == false)
                if  ( value <= 0 || value > app.video.NumFrames )

                    msgbox('Frame not present!');
                    app.FrameSpinner.Value = 1;

                else
                    app.Time.Text = string(displayTime(app));
                    [app.data.height,app.data.time] = FilteringFrame(app);

                    app.Update_Plots();
                    app.Average_Calc(); %show average values
                end
            else
                app.FrameSpinner.Value = 1;
                msgbox("You need to load a video before!",'Error','warn');
            end

        end

        % Value changed function: GinputButton
        function GinputButtonValueChanged(app, event)
            if (isempty(app.video) == false)
                f = figure(1);

                plot(app.data.time,app.data.height,'LineWidth',3,'Color',[0.790 0.932 0.234]);

                %just colorful stuff
                set(f,"Color",[0.18,0.20,0.28]);
                set(gca,"Color",[0.18,0.20,0.28],"YColor",[0.790 0.932 0.234],"XColor",[0.790 0.932 0.234],...
                    "FontSize",16);

                ylim([min(app.data.height)-10 max(app.data.height)+10]);% [ -50 300];

                title('Do 2 clicks to select the range', "Color",[0.790 0.932 0.234],"FontSize",22)
                ylabel('Speed [cm/s]',"Color",[0.790 0.932 0.234])
                xlabel('Time [s]',"Color",[0.790 0.932 0.234])

                [x,~] = ginput(2);
                close(f)
                x = sort(x);
                ref = dsearchn(app.data.time',x);

                %           re calculate the time to measure from 0 to the end of the
                %           cutted plot!!! So the time is adjusted!
                time = (app.data.time(ref(1):ref(2)));
                time = time - time(1);


                %integral
                %trapz(time, app.data.filtered(ref(1):ref(2)));
                %mean
                % meanvalue = sum(app.data.filtered(ref(1):ref(2))) / time(end);
                meanvalue = mean(app.data.height(ref(1):ref(2)));
                msgbox(string(round(meanvalue,2)) + ' cm/s')
            else
                msgbox("You need to load a video before!",'Error','warn')
            end

        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            if(isempty(app.video) == false)

                %value = app.SaveButton.Value;
                [path,name] = fileparts(app.files);
                %             msgbox(path)

                if ~exist([path '/Elaborated/'], 'dir')
                    mkdir([path '/Elaborated/'])
                end
                raw = app.data.height';

                Path_saveFile= [path '/Elaborated/' name '_frame_' num2str(app.FrameSpinner.Value) '.mat']; %change
                save(Path_saveFile,'raw','-mat');
            end
        end

        % Menu selected function: LoadFile
        function LoadFileMenuSelected(app, event)

            %addpath with functions where the app is
            tmp = mfilename('fullpath');
            tmp = erase(tmp,mfilename);
            cd(tmp);
            addpath(genpath(tmp));

            if isempty(app.Parameters)                
                app.Parameters = load([tmp '/Parms/Default_Parameters.mat']);
            end
            %get video file formats
            reader = 'VideoReader';
            file_formats = eval([reader '.getFileFormats']);
            format_list{1,1} = [];
            format_list{1,2} = 'All Video Files (';
            for i = 1:length(file_formats)
                format_list{1,1} = [format_list{1,1} '*.' file_formats(i).get.Extension ';'];
                format_list{1,2} = [format_list{1,2} file_formats(i).get.Description ', '];
                format_list{i+1,1} = ['*.' file_formats(i).get.Extension];
                format_list{i+1,2} = ['*.' file_formats(i).get.Extension ' - ' file_formats(i).get.Description];
            end

            format_list{1,2} = [format_list{1,2}(1:end-2) ')'];

            %app.files = uipickfiles('FilterSpec','*.mp4');
            [name, path] = uigetfile(format_list, 'Pick a movie file');
            app.files = [path name];

            if (~isnumeric(app.files))

                [~,name,ext] = fileparts(app.files);
                app.FileNameLabel.Text = [name ext] ;
                app.video = VideoReader(app.files);

                %write num of frame
                app.FrameLabel.Text = num2str(app.video.NumFrames);
                app.FrameSpinner.Value = 1;
                app.FrameSpinner.Limits = [1 app.video.NumFrames];

                %load eco parameters masks (default)
                %addpath with function where the app is
                tmp = mfilename('fullpath');
                tmp = erase(tmp,mfilename);
                path_mask = [tmp '/Functions/Mask'];

                list = dir(fullfile(path_mask,'*.m'));

                for ii=1:length(list)
                    nomi{ii} = list(ii).name;
                end

                nomi = erase(string(nomi),'.m');
                nomi = ['Default' nomi]; %add default first position

                app.SelectMaskDropDown.Items = nomi;

                %write frequency and time
                app.Frequency.Value = num2str(round(app.video.FrameRate,2));
                app.Time.Text = string(displayTime(app));

                %SelectMaskDropDownValueChanged(app);

                [app.data.height, app.data.time] = FilteringFrame(app);

                %update plots
                app.Update_Plots();
                app.Average_Calc();

            end


        end

        % Menu selected function: CreateParms
        function CreateParmsMenuSelected(app, event)

            if(isempty(app.video) == 0)


                msgbox({"Calibrate order"; "1) Time (x axis)"; "2) Velocity (y axis)"});
                waitforbuttonpress();

                Parameters.scaleTime = spatial_calibration(app.video,app.FrameSpinner.Value);
                Parameters.scale     = spatial_calibration(app.video,app.FrameSpinner.Value);

                msgbox("Crop the Region of Interest! Please do not include the ticks of the axes");
                waitforbuttonpress();

                figure(1)
                [~,Parameters.rect] = imcrop(read(app.video,app.FrameSpinner.Value));
                close

                %save them
                uisave("Parameters")

                app.Parameters.Parameters.scale = Parameters.scale;
                app.Parameters.Parameters.scaleTime = Parameters.scaleTime;
                app.Parameters.Parameters.rect = Parameters.rect;
            else
                msgbox("You need to load a video before!",'Error','warn')

            end
        end

        % Menu selected function: LoadParms
        function LoadParmsMenuSelected(app, event)

            %addpath with functions where the app is
            tmp = mfilename('fullpath');
            tmp = erase(tmp,mfilename);
            addpath(genpath(tmp));

            %file = uipickfiles('FilterSpec',[cd '/Parms/*.mat']);
            [FileName,PathName] = uigetfile([tmp '/Parms/*.mat']);
            file = [PathName FileName];

            %load the parms into the app
            if (~isnumeric(file))

                load(file);

                app.Parameters.Parameters.scale = Parameters.scale;
                app.Parameters.Parameters.scaleTime = Parameters.scaleTime;
                app.Parameters.Parameters.rect = Parameters.rect;
                app.FrameSpinner.Value = 1;

            end

            %apply them whether a video is loaded
            if (~isempty(app.video))

                %re-calc data with new parameters
                [app.data.height,app.data.time] = FilteringFrame(app); %update raw data

                app.Update_Plots();
                app.Average_Calc(); %show average values

            end

        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            exit
        end

        % Value changed function: SelectMaskDropDown
        function SelectMaskDropDownValueChanged(app, event)

            [app.data.height,app.data.time] = FilteringFrame(app);
            app.Update_Plots();
        end

        % Menu selected function: ProcessAllFramesMenu
        function ProcessAllFramesMenuSelected(app, event)
            if (isempty(app.video) == false)
                BatchProcess(app);
            else
                msgbox("You need to load a video before!",'Error','warn')
            end
        end

        % Menu selected function: CreateMaskMenu
        function CreateMaskMenuSelected(app, event)
            if (isempty(app.video) == false)
                prompt = {'Enter frame to create Mask:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'1'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);

                data = read(app.video,str2double(answer));
                %assignin("base","data",data); %export var to workspace to open with colorThresholder

                colorThresholder(data); %open selected frame in colorThresholder
            else
                msgbox("You need to load a video before!",'Error','warn')
            end

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            showHyperlinkDialog(app);
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonPushed(app, event)


            if(isempty(app.video) == false)

                app.play = -app.play;   %always do the contrary, avoid fucking useless if(s)

                while (app.play > 0 && app.FrameSpinner.Value < app.video.NumFrames)
                    [app.data.height,app.data.time] = FilteringFrame(app);

                    app.Update_Plots();
                    app.Average_Calc();                       %update calculation
                    app.Time.Text = string(displayTime(app)); %update time

                    if (app.FrameSpinner.Value < app.video.NumFrames)
                        app.FrameSpinner.Value = app.FrameSpinner.Value +1;
                    end
                end
                if (app.FrameSpinner.Value == app.video.NumFrames)
                    app.play = -1;  %if video playing ends reset the check so don't push the button two times
                end
            end
        end

        % Menu selected function: VesselEdgeDetectionMenu
        function VesselEdgeDetectionMenuSelected(app, event)
            %%
            if (isempty(app.video) == false)
                selOpt = dlg_plotting_vessel(app);
                Edge_DetectionAnalysis(app,selOpt);
            else
                msgbox("You need to load a video before!",'Error','warn')
            end
        end

        % Menu selected function: BatchProcessMenu
        function BatchProcessMenuSelected(app, event)
            BatchFilesProcess(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadFile = uimenu(app.FileMenu);
            app.LoadFile.MenuSelectedFcn = createCallbackFcn(app, @LoadFileMenuSelected, true);
            app.LoadFile.Text = 'Load File';
            app.Parms = uimenu(app.FileMenu);
            app.Parms.Text = 'Parms';
            app.LoadParms = uimenu(app.Parms);
            app.LoadParms.MenuSelectedFcn = createCallbackFcn(app, @LoadParmsMenuSelected, true);
            app.LoadParms.Text = 'Load Parms';
            app.CreateParms = uimenu(app.Parms);
            app.CreateParms.MenuSelectedFcn = createCallbackFcn(app, @CreateParmsMenuSelected, true);
            app.CreateParms.Text = 'Create Parms';
            app.MaskMenu = uimenu(app.FileMenu);
            app.MaskMenu.Text = 'Mask';
            app.CreateMaskMenu = uimenu(app.MaskMenu);
            app.CreateMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateMaskMenuSelected, true);
            app.CreateMaskMenu.Text = 'Create Mask';
            app.ProcessAllFramesMenu = uimenu(app.FileMenu);
            app.ProcessAllFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessAllFramesMenuSelected, true);
            app.ProcessAllFramesMenu.Text = 'Process All Frames';
            app.BatchProcessMenu = uimenu(app.FileMenu);
            app.BatchProcessMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchProcessMenuSelected, true);
            app.BatchProcessMenu.Text = 'Batch Process';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.DiameterDetectionMenu = uimenu(app.UIFigure);
            app.DiameterDetectionMenu.Text = 'Diameter Detection';
            app.VesselEdgeDetectionMenu = uimenu(app.DiameterDetectionMenu);
            app.VesselEdgeDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @VesselEdgeDetectionMenuSelected, true);
            app.VesselEdgeDetectionMenu.Text = 'Vessel Edge Detection';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileNameLabel = uilabel(app.LeftPanel);
            app.FileNameLabel.Text = {'----'; ''};
            app.FileNameLabel_2 = uilabel(app.LeftPanel);
            app.FileNameLabel_2.Text = 'File Name';
            app.SaveButton = uibutton(app.LeftPanel, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = {'Save'; ''};
            app.FrequencyHzLabel = uilabel(app.LeftPanel);
            app.FrequencyHzLabel.Text = 'Video Rate (fps)';
            app.Frequency = uieditfield(app.LeftPanel, 'text');
            app.Frequency.Editable = 'off';
            app.Frequency.Value = '1';
            app.GinputButton = uibutton(app.LeftPanel, 'state');
            app.GinputButton.ValueChangedFcn = createCallbackFcn(app, @GinputButtonValueChanged, true);
            app.GinputButton.Text = {'Ginput'; ''};
            app.SelectMaskDropDownLabel = uilabel(app.LeftPanel);
            app.SelectMaskDropDownLabel.Text = 'Select Mask';
            app.SelectMaskDropDown = uidropdown(app.LeftPanel);
            app.SelectMaskDropDown.Items = {};
            app.SelectMaskDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMaskDropDownValueChanged, true);
            app.SelectMaskDropDown.Value = {};
            app.SavecurrentframedataasanarrayLabel = uilabel(app.LeftPanel);
            app.SavecurrentframedataasanarrayLabel.Text = {'Save current frame '; 'data as an array'};
            app.FrameSpinnerLabel = uilabel(app.LeftPanel);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.LeftPanel);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.FrameLabel = uilabel(app.LeftPanel);
            app.FrameLabel.Text = '1';
            app.TimeLabel = uilabel(app.LeftPanel);
            app.TimeLabel.Text = 'Time';
            app.Time = uilabel(app.LeftPanel);
            app.Time.Text = '00:00.00';
            app.AveragecmsLabel = uilabel(app.LeftPanel);
            app.AveragecmsLabel.Text = 'Average (cm/s):';
            app.AverageLabel = uilabel(app.LeftPanel);
            app.AverageLabel.Text = '----';
            app.PlayPauseButton = uibutton(app.LeftPanel, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonPushed, true);
            app.PlayPauseButton.Icon = fullfile(pathToMLAPP, 'MISC', 'play_button.png');
            app.PlayPauseButton.Text = '';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MISC', 'VascuTrack_Icon_Git.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_Raw = uiaxes(app.RightPanel);
            title(app.UIAxes_Raw, {''; ''})
            ylabel(app.UIAxes_Raw, 'Velocity (cm/s)')
            app.UIAxes_Raw.XTick = [];
            app.UIAxes_Raw.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_Raw.LineStyleOrder = {'-'; '-'};
            colormap(app.UIAxes_Raw, 'jet')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TGVascutrack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end